classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LOADTab                       matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        Label2                        matlab.ui.control.Label
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes3_2                     matlab.ui.control.UIAxes
        SEGMENTATIONTab               matlab.ui.container.Tab
        Label_11                      matlab.ui.control.Label
        Label2_11                     matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes7                       matlab.ui.control.UIAxes
        MODELCORNERDETECTIONTab       matlab.ui.container.Tab
        Label_12                      matlab.ui.control.Label
        Label2_12                     matlab.ui.control.Label
        UIAxes8                       matlab.ui.control.UIAxes
        UIAxes9                       matlab.ui.control.UIAxes
        MATCHINGTab_2                 matlab.ui.container.Tab
        Label_13                      matlab.ui.control.Label
        Label2_13                     matlab.ui.control.Label
        UIAxes10                      matlab.ui.control.UIAxes
        UIAxes11                      matlab.ui.control.UIAxes
        Panel_6                       matlab.ui.container.Panel
        CommandsPanelLabel_6          matlab.ui.control.Label
        MinPeakDistanceEditField_9Label  matlab.ui.control.Label
        MinPeakDistanceEditField      matlab.ui.control.NumericEditField
        SizeSmoothingWindowEditField_9Label  matlab.ui.control.Label
        SizeSmoothingWindowEditField  matlab.ui.control.NumericEditField
        STDGainEditField_9Label       matlab.ui.control.Label
        STDGainEditField              matlab.ui.control.NumericEditField
        LOADButton_5                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        SEGMENTButton                 matlab.ui.control.Button
        MATCHButton                   matlab.ui.control.Button
        MODCORButton                  matlab.ui.control.Button
        UIAxes12                      matlab.ui.control.UIAxes
        PauseButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        Container = objContainer(true);
        autoLoad = true;
        inPause = false;
    end

    methods (Access = private)
    
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.UIAxes8.Visible = 'off';
            app.UIAxes9.Visible = 'off';
            app.UIAxes10.Visible = 'off';
            app.UIAxes11.Visible = 'off';
            app.UIAxes2_2.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            app.UIAxes4.Visible = 'off';
            app.UIAxes7.Visible = 'off';
            app.UIAxes5.Visible = 'off';
            app.UIAxes3_2.Visible = 'off';
            app.SEGMENTButton.Enable = 'off';
            app.MATCHButton.Enable = 'off';
            app.MODCORButton.Enable = 'off';
            imshow(imread('E:\Tesi\Ottica\Calibration\100MSDCF\new_programGUI\Logo\LOGOTU.png'),'Parent',app.UIAxes12);
            load(app, app);
        end

        % Button pushed function: LOADButton_5
        function load(app, event)
            app.SEGMENTButton.Enable = 'off';
            app.MODCORButton.Enable = 'off';
            app.MATCHButton.Enable = 'off';
            addpath('E:\ProgrammiHD\mexopencv-master');
            app.Container = objContainer(true);
            app.Container.app = app;
            if app.autoLoad
                path = 'E:\Tesi\Ottica\Calibration\100MSDCF\foto\Evaluation\5_c\4f2';
                app.autoLoad = false;
            else
                path = uigetdir('E:\Tesi\Ottica\Calibration\100MSDCF\foto\Evaluation');
            end
            parpar = load([path,'\parpar.mat']);
            orig = imread([path,'\fore.JPG']);
            orig_bg = imread([path,'\back.JPG']);
            app.MinPeakDistanceEditField.Value = parpar.parpar.mpd;
            app.STDGainEditField.Value = parpar.parpar.confidence;
            app.Container.checker_vector = parpar.parpar.checker_vector;
            app.Container.checker_center = parpar.parpar.checker_center;
            app.Container.img_dim  = size(app.Container.I);
            app.Container.mpd = parpar.parpar.mpd;
            app.SizeSmoothingWindowEditField.Value = 6;
            app.Container.windowSize = 6;
            app.Container.op_th = 15;
            app.Container.num_square = 5;
            app.Container.fraction = 20;
            app.Container.confidence = parpar.parpar.confidence;
            [app.Container.I, app.Container.I_BG, app.Container.O, app.Container.O_BG, app.Container.BB] = normalize_image(orig, orig_bg, parpar.parpar.bb);
            app.UIAxes2.Visible = 'on';
            app.UIAxes2_2.Visible = 'on';
            app.UIAxes3.Visible = 'on';
            app.UIAxes3_2.Visible = 'on';
            cla(app.UIAxes2); cla(app.UIAxes3);
            imshow(app.Container.I,'Parent',app.UIAxes2); imshow(app.Container.I,'Parent',app.UIAxes2_2); 
            imshow(app.Container.I_BG,'Parent',app.UIAxes3); imshow(app.Container.I_BG,'Parent',app.UIAxes3_2);
            imshow(app.Container.checker_vector,'Parent',app.UIAxes);
            app.SizeSmoothingWindowEditField.Editable = 'on';
            app.STDGainEditField.Editable = 'on';
            app.MinPeakDistanceEditField.Editable = 'on';
            app.SEGMENTButton.Enable = 'on';
            app.Container.path = [path,'\'];
        end

        % Button pushed function: SEGMENTATIONButton, SEGMENTButton
        function segment(app, event)
            app.TabGroup.SelectedTab = app.SEGMENTATIONTab;
            %% INIT
            Container = app.Container;
            checker_vector = app.Container.checker_vector;
            app.Container.windowSize = app.Container.windowSize;
            app.Container.confidence = app.STDGainEditField.Value;
            app.Container.mpd = app.MinPeakDistanceEditField.Value;
            app.SizeSmoothingWindowEditField.Editable = 'off';
            app.STDGainEditField.Editable = 'off';
            app.MinPeakDistanceEditField.Editable = 'off';
            %% LOGIC
            segmentation;
            [obj_chess, transitions, Container] = getColorBoundary(obj_red, obj_green, obj_blue, obj_yellow, checker_vector, Container);
            segmented_image = obj_red.true_color_mask + obj_green.true_color_mask + obj_blue.true_color_mask + obj_yellow.true_color_mask;
            imshow(segmented_image,'Parent',app.UIAxes5);
            %% END
            Container.obj_chess = obj_chess;
            Container.transitions = transitions;
            app.Container = Container;
            app.SEGMENTButton.Enable = 'off';
            app.MODCORButton.Enable = 'on';
        end

        % Button pushed function: MODCORButton
        function modeling(app, event)
            %% INIT
            app.TabGroup.SelectedTab = app.MODELCORNERDETECTIONTab;
            Container = app.Container;
            checker_vector = app.Container.checker_vector;
            obj_chess = app.Container.obj_chess;
            transitions = app.Container.transitions;
            %% LOGIC
            obj_chess = completeComputeChess(obj_chess, transitions, Container);
            Container.obj_chess = error_check(obj_chess(1), obj_chess(2), obj_chess(3),  obj_chess(4));
            fuse = show_result(Container, false, '');
            modeling;
            [left_center_axis, right_center_axis, mid_center_axis] = generate_central_axis_two(Container);
            Container.pointsArray = calculate_corners(Container, left_center_axis, right_center_axis, mid_center_axis, app.Container.path);
            %% END
            app.Container = Container;
            app.MATCHButton.Enable = 'on';
            app.MODCORButton.Enable = 'off';
        end

        % Button pushed function: MATCHButton
        function match(app, event)
            %% INIT
            app.TabGroup.SelectedTab = app.MATCHINGTab_2;
            Container = app.Container;
            checker_vector = app.Container.checker_vector;
            pointsArray = app.Container.pointsArray;
            %% LOGIC
            Container = points_split(Container, pointsArray);
            Container = points_allign(Container);
            Container = generate_mapping(Container, checker_vector);
            show_mapping(Container);
        end

        % Callback function
        function update(app, event)
            value = app.STDGainEditField.Value;
            
        end

        % Button pushed function: PauseButton
        function go_pause(app, event)
            if app.inPause
                app.inPause = ~app.inPause;
                uiresume;
            else
                app.inPause = ~app.inPause;
                uiwait;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LOADTab = uitab(app.TabGroup);
            app.LOADTab.Title = 'LOAD';
            app.LOADTab.Units = 'pixels';
            app.Label = uilabel(app.LOADTab);
            app.Label.Text = 'SMILE PROJECT';
            app.Label2 = uilabel(app.LOADTab);
            app.Label2.Text = 'Facial Mirror System Calibration';
            app.UIAxes2_2 = uiaxes(app.LOADTab);
            title(app.UIAxes2_2, 'Foreground');
            app.UIAxes3_2 = uiaxes(app.LOADTab);
            title(app.UIAxes3_2, 'Background');
            app.SEGMENTATIONTab = uitab(app.TabGroup);
            app.SEGMENTATIONTab.Title = 'SEGMENTATION';
            app.SEGMENTATIONTab.Units = 'pixels';
            app.Label_11 = uilabel(app.SEGMENTATIONTab);
            app.Label_11.Text = 'SMILE PROJECT';
            app.Label2_11 = uilabel(app.SEGMENTATIONTab);
            app.Label2_11.Text = 'Facial Mirror System Calibration';
            app.UIAxes4 = uiaxes(app.SEGMENTATIONTab);
            app.UIAxes5 = uiaxes(app.SEGMENTATIONTab);
            app.UIAxes7 = uiaxes(app.SEGMENTATIONTab);
            title(app.UIAxes7, 'Hue Histogram');
            app.MODELCORNERDETECTIONTab = uitab(app.TabGroup);
            app.MODELCORNERDETECTIONTab.Title = 'MODEL & CORNER DETECTION';
            app.MODELCORNERDETECTIONTab.Units = 'pixels';
            app.Label_12 = uilabel(app.MODELCORNERDETECTIONTab);
            app.Label_12.Text = 'SMILE PROJECT';
            app.Label2_12 = uilabel(app.MODELCORNERDETECTIONTab);
            app.Label2_12.Text = 'Facial Mirror System Calibration';
            app.UIAxes8 = uiaxes(app.MODELCORNERDETECTIONTab);
            app.UIAxes9 = uiaxes(app.MODELCORNERDETECTIONTab);
            app.MATCHINGTab_2 = uitab(app.TabGroup);
            app.MATCHINGTab_2.Title = 'MATCHING';
            app.MATCHINGTab_2.Units = 'pixels';
            app.Label_13 = uilabel(app.MATCHINGTab_2);
            app.Label_13.Text = 'SMILE PROJECT';
            app.Label2_13 = uilabel(app.MATCHINGTab_2);
            app.Label2_13.Text = 'Facial Mirror System Calibration';
            app.UIAxes10 = uiaxes(app.MATCHINGTab_2);
            app.UIAxes11 = uiaxes(app.MATCHINGTab_2);
            app.Panel_6 = uipanel(app.UIFigure);
            app.CommandsPanelLabel_6 = uilabel(app.Panel_6);
            app.CommandsPanelLabel_6.Text = 'Commands Panel';
            app.MinPeakDistanceEditField_9Label = uilabel(app.Panel_6);
            app.MinPeakDistanceEditField_9Label.Text = 'Min Peak Distance';
            app.MinPeakDistanceEditField = uieditfield(app.Panel_6, 'numeric');
            app.MinPeakDistanceEditField.Editable = 'off';
            app.SizeSmoothingWindowEditField_9Label = uilabel(app.Panel_6);
            app.SizeSmoothingWindowEditField_9Label.Text = 'Size Smoothing Window';
            app.SizeSmoothingWindowEditField = uieditfield(app.Panel_6, 'numeric');
            app.SizeSmoothingWindowEditField.Editable = 'off';
            app.STDGainEditField_9Label = uilabel(app.Panel_6);
            app.STDGainEditField_9Label.Text = 'STD Gain';
            app.STDGainEditField = uieditfield(app.Panel_6, 'numeric');
            app.STDGainEditField.Editable = 'off';
            app.LOADButton_5 = uibutton(app.Panel_6, 'push');
            app.LOADButton_5.ButtonPushedFcn = createCallbackFcn(app, @load, true);
            app.LOADButton_5.Text = 'LOAD';
            app.UIAxes = uiaxes(app.Panel_6);
            title(app.UIAxes, 'Checker vector');
            app.UIAxes2 = uiaxes(app.Panel_6);
            title(app.UIAxes2, 'Foreground');
            app.UIAxes3 = uiaxes(app.Panel_6);
            title(app.UIAxes3, 'Background');
            app.SEGMENTButton = uibutton(app.Panel_6, 'push');
            app.SEGMENTButton.ButtonPushedFcn = createCallbackFcn(app, @segment, true);
            app.SEGMENTButton.Text = 'SEGMENT';
            app.MATCHButton = uibutton(app.Panel_6, 'push');
            app.MATCHButton.ButtonPushedFcn = createCallbackFcn(app, @match, true);
            app.MATCHButton.Text = 'MATCH';
            app.MODCORButton = uibutton(app.Panel_6, 'push');
            app.MODCORButton.ButtonPushedFcn = createCallbackFcn(app, @modeling, true);
            app.MODCORButton.Text = 'MOD&COR';
            app.UIAxes12 = uiaxes(app.Panel_6);
            app.PauseButton = uibutton(app.Panel_6, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @go_pause, true);
            app.PauseButton.Text = 'Pause';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end